classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout3                    matlab.ui.container.GridLayout
        GridLayout20_2                 matlab.ui.container.GridLayout
        InicioButton_2                 matlab.ui.control.Button
        UIAxesSpectrogramProc          matlab.ui.control.UIAxes
        UIAxesFrecuenciaProc           matlab.ui.control.UIAxes
        UIAxesTiempoProc               matlab.ui.control.UIAxes
        GridLayout2                    matlab.ui.container.GridLayout
        GridLayout10                   matlab.ui.container.GridLayout
        TextPresion                    matlab.ui.control.TextArea
        NiveldepresinsonoraButton      matlab.ui.control.Button
        TextPotenciamedia              matlab.ui.control.TextArea
        PotenciamediaButton            matlab.ui.control.Button
        GridLayout20                   matlab.ui.container.GridLayout
        InicioButton                   matlab.ui.control.Button
        PlayPauseButton                matlab.ui.control.Button
        ActualizarButton               matlab.ui.control.Button
        UIAxesTiempo                   matlab.ui.control.UIAxes
        UIAxesSpectrogram              matlab.ui.control.UIAxes
        UIAxesFrecuencia               matlab.ui.control.UIAxes
        GestordearchivosPanel          matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        CombinarButton                 matlab.ui.control.Button
        TextArea                       matlab.ui.control.TextArea
        GridLayout7                    matlab.ui.container.GridLayout
        GridLayout6                    matlab.ui.container.GridLayout
        Archivos                       matlab.ui.control.ListBox
        ListBoxLabel                   matlab.ui.control.Label
        GridLayout5                    matlab.ui.container.GridLayout
        GuardarButton                  matlab.ui.control.Button
        Button_2                       matlab.ui.control.Button
        Button                         matlab.ui.control.Button
        Button_5                       matlab.ui.control.Button
        Button_3                       matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        GeneradorTab                   matlab.ui.container.Tab
        GridLayout12                   matlab.ui.container.GridLayout
        DuracionEditField              matlab.ui.control.NumericEditField
        FrecuenciaLabel_3              matlab.ui.control.Label
        FrecMuestreoEditField          matlab.ui.control.NumericEditField
        FrecuenciaLabel_2              matlab.ui.control.Label
        TrapezoidalPanel               matlab.ui.container.Panel
        GridLayout22                   matlab.ui.container.GridLayout
        TiempoAltoEditField            matlab.ui.control.NumericEditField
        TiempodesubidaLabel_3          matlab.ui.control.Label
        TiempoBajadaEditField          matlab.ui.control.NumericEditField
        TiempodesubidaLabel_2          matlab.ui.control.Label
        TiempoSubidaEditField          matlab.ui.control.NumericEditField
        TiempodesubidaLabel            matlab.ui.control.Label
        OndaTriangularPanel            matlab.ui.container.ButtonGroup
        SimetriaEditField              matlab.ui.control.NumericEditField
        SimetraLabel                   matlab.ui.control.Label
        OndaCuadradaPanel              matlab.ui.container.ButtonGroup
        DutyCycleEditField             matlab.ui.control.NumericEditField
        DutyCycleLabel                 matlab.ui.control.Label
        GenerarSealButton              matlab.ui.control.Button
        GrabacinPanel                  matlab.ui.container.Panel
        RecordingStatusLabel           matlab.ui.control.TextArea
        RecordButton                   matlab.ui.control.Button
        DesfasegradosEditField         matlab.ui.control.NumericEditField
        AmplitudLabel_2                matlab.ui.control.Label
        AmplitudGenerada               matlab.ui.control.NumericEditField
        FormaOnda                      matlab.ui.control.DropDown
        FormadeOndaLabel               matlab.ui.control.Label
        AmplitudLabel                  matlab.ui.control.Label
        FrecuenciaGenerada             matlab.ui.control.NumericEditField
        FrecuenciaLabel                matlab.ui.control.Label
        ProcesadoTab                   matlab.ui.container.Tab
        GridLayout13                   matlab.ui.container.GridLayout
        AliasingPanel                  matlab.ui.container.Panel
        GridLayout19                   matlab.ui.container.GridLayout
        NyquistArea                    matlab.ui.control.TextArea
        AliasingButton                 matlab.ui.control.Button
        Aliasing                       matlab.ui.control.EditField
        IntroduzcaunanuevaFsLabel      matlab.ui.control.Label
        ModDemodPanel                  matlab.ui.container.Panel
        GridLayout17                   matlab.ui.container.GridLayout
        HzLabel                        matlab.ui.control.Label
        ModularButton                  matlab.ui.control.Button
        DemodularButton                matlab.ui.control.Button
        FrecuenciaPortadoraEditField   matlab.ui.control.NumericEditField
        FrecuenciaPortadoraFieldLabel  matlab.ui.control.Label
        TipoModulacionButtonGroup      matlab.ui.container.ButtonGroup
        FMButton                       matlab.ui.control.RadioButton
        AMButton                       matlab.ui.control.RadioButton
        RUIDOPanel                     matlab.ui.container.Panel
        GridLayout16                   matlab.ui.container.GridLayout
        AplicarFiltroButton            matlab.ui.control.Button
        FrecCorteEditField             matlab.ui.control.NumericEditField
        freccorteHzLabel               matlab.ui.control.Label
        FiltrecuperacinFPBLabel        matlab.ui.control.Label
        AplicarRuidoButton             matlab.ui.control.Button
        RuidoGaussianoLabel            matlab.ui.control.Label
        PotenciaRuidoEditField         matlab.ui.control.NumericEditField
        PotenciaEditFieldLabel         matlab.ui.control.Label
        OperacionesElementalesButtonGroup  matlab.ui.container.ButtonGroup
        InversionTemporalButton        matlab.ui.control.ToggleButton
        ExpansionButton                matlab.ui.control.ToggleButton
        CompresionButton               matlab.ui.control.ToggleButton
        OpcionesPanel                  matlab.ui.container.Panel
        GridLayout14                   matlab.ui.container.GridLayout
        AplicarButton                  matlab.ui.control.Button
        FactorEditField_2              matlab.ui.control.NumericEditField
        FactorEditField_2Label         matlab.ui.control.Label
        DominiodeOperacionButtonGroup  matlab.ui.container.ButtonGroup
        FrecuenciaButton               matlab.ui.control.RadioButton
        TiempoButton                   matlab.ui.control.RadioButton
    end

properties (Access = private)
        %Almacen estructurado para organizar las variables
        %Name: nombre del archivo
        %data= x(t)
        %fs= frecuencia de muestreo (Hz) --> para dominio de la frecuencia

        SignalData = struct('Name', {}, 'Data', {}, 'Fs', {}); % Almacén para las señales
        %Name: Nombre del archivo (ej: 'MiGrabacion.wav').
        %Data: El vector de datos de la señal, $x(t)
        %Fs: La Frecuencia de Muestreo (en Hz).
        AudioPlayer = []; % Objeto para controlar Play/Pause
        AudioRecorder= [];
        
        PlaybackMarker = []; %handle para el marcador
        %%%%%%%%%%%%%%%%%%%%%
        % Parámetros de la señal a generar:
        GeneratedFs = 44100; % Frecuencia de muestreo por defecto (Hz)
        Duration = 1;        % Duración de la señal por defecto (segundos)

        %creamoso una memoria temporal para las señales procesadas
        ProcessedData = []; % Aquí guardaremos la Y de la gráfica roja
        ProcessedFs = [];   % Aquí guardaremos la Fs de la gráfica roja

        SelectedOperation = 'Compresion'; % Almacena la operación activa (Compresion, Expansion, Inversion Temporal)
    
        % Controles dentro del Panel (Creados en createComponents, pero definidos aquí para acceso)
        AplicarProcesadoButton matlab.ui.control.Button % Botón para aplicar la operación
        FactorLabel          matlab.ui.control.Label   % Etiqueta para el factor
        FactorEditField      matlab.ui.control.NumericEditField % Campo de entrada del factor
        DominioButtonGroup   matlab.ui.container.ButtonGroup % Grupo para seleccionar Dominio (Tiempo/Frecuencia)
        TiempoRadio          matlab.ui.control.RadioButton % Radio para Dominio: Tiempo
        FrecuenciaRadio      matlab.ui.control.RadioButton % Radio para Dominio: Frecuencia

end

methods (Access = private)
        
        %Para que el Usuario vea las señales disponibles
        function updateListBox(app)
            names = {app.SignalData.Name}; %toma el array de singal data y accede al campo Name
            
            %si no hay señales cargadas
            if isempty(names)
                app.Archivos.Items = {}; %limpia la lista visible
                app.Archivos.Value = {}; %deselecciona
            %si hay señales cargadas
            else
                app.Archivos.Items = names; %asigna la lista 
                %Items: propiedad de la listbox
            end
        end

        % Obtiene la señal seleccionada en la lista
        function [y, Fs] = getSelectedSignal(app)

            %inicializamos las variables
            y = [];
            Fs = [];
            selectedValue = app.Archivos.Value; %se obtiene el nombre seleccionado
            %al introducir el multiselect selectedName sera ahora un cell
            %array
            if isempty(selectedValue)
                return; % No hay nada seleccionado
            end
            
            % Comprobar si se ha seleccionado MÁS de una señal
            if length(selectedValue) > 1
                % Si es así, mostrar una alerta y salir.
                uialert(app.UIFigure, 'Por favor, seleccione solo una señal para esta operación.', 'Selección Múltiple');
                return; % Devuelve y=[] y Fs=[]
            end
            
            % Extraemos el elemento de la celda
            if iscell(selectedValue)
                %No queremos la celda {'MiAudio.wav'} queremos string así 
                %que elegimos el primer elemento de dentro de la celda
                selectedName = selectedValue{1};
            else
             
                selectedName = selectedValue; %por si acaso el selectedValue ya es un string
           
                %esto será cuando hacemos (en otros callbacks):
                %app.Archivos.Value = filename; 
            end

            if isempty(selectedName)
                return; % No hay nada seleccionado
            end

            % Busca la señal en nuestro almacén
            %strcmp: string compare
            %idx= vector con 0 donde no coincida y 1 donde coincida
            idx = strcmp({app.SignalData.Name}, selectedName);

            if any(idx) %comprueba si hay algun elemneto no nulo
                % Devuelve la primera (1) que coincida (no debería haber duplicados)
                %dataStruct = información sobre la señal seleccionada
                dataStruct = app.SignalData(find(idx, 1));
                
                %asignamos los campos y = x(t) y fs
                y = dataStruct.Data;
                Fs = dataStruct.Fs;
            end
        end

        % Dibuja las gráficas
        function updatePlots(app)
            %%%%%%%
            % vaciamos el contenido de los textos que muestran la potencia
            app.TextPotenciamedia.Value = ''; 
            app.TextPresion.Value = '';

            %obtenemos la señal
            [y, Fs] = getSelectedSignal(app);
            

            %si no hay nada
            if isempty(y)
               
                %cla = clear Axes
                cla(app.UIAxesTiempo);
                cla(app.UIAxesFrecuencia);
                

                return; % No hay nada que dibujar
            end
            
 
            % --- 1. Gráfica del Tiempo --- 

         
            cla(app.UIAxesTiempo); 
            L = length(y); %long señal

            % Vector de tiempo
            %0:L-1 índices de las muestras
            %(índice /Fs) = tiempo en segundos

            t = (0:L-1) / Fs;  
            
            % Forzamos el límite del eje X a la duración exacta de la señal (t(end)).
            xlim(app.UIAxesTiempo, [0, t(end)]); 
            
            
            % Si la señal es estéreo, dibuja solo un canal
            if size(y, 2) > 1
                y_plot = y(:, 1); % Usar solo el canal izquierdo
            else
                y_plot = y;
            end
           
            
             % Ajuste de límites Y (para centrar la señal) *1.1 para darle
             % un margen
            maxY = max(abs(y_plot));

            % Asegurar que el límite Y no sea cero. Usar un valor mínimo.
            maxY_safe = max(maxY, 1e-6); 

            %dibujamos la gráfica y nombramos los ejes
            plot(app.UIAxesTiempo, t, y_plot); %plot(grafica, x, y)
            xlabel(app.UIAxesTiempo, 'Tiempo (s)'); 
            ylabel(app.UIAxesTiempo, 'Amplitud (v)'); 
            title(app.UIAxesTiempo, ['Señal: ' app.Archivos.Value]);
            grid(app.UIAxesTiempo, 'on'); %activa el grid para mejorar lectura

            % --- 2. Gráfica de Frecuencia (FFT) --- 
            cla(app.UIAxesFrecuencia); % Limpia la gráfica anterior
            

            % Usamos la misma y_plot
            L = length(y_plot); %longitud
            Y = fft(y_plot); %devuelve el espectro de frecuencia Y
            
            %nos quedamos solo con la amplitud (descartamos la información de fase)
            P2 = abs(Y/L); %normalizar la amplitud

            %espectro simétrico, solo necesitamos la mitad
            %de 0Hz a Fs/2
            P1 = P2(1:floor(L/2)+1);
            f = Fs*(0:(floor(L/2)))/L;
            
            maxP1 = max(abs(P1));
            maxP1_safe = max(maxP1, 1e-6);
            ylim(app.UIAxesFrecuencia, [0 , maxP1_safe*1.1 ])

            %dibujamos la gráfica y nombramos los ejes
            plot(app.UIAxesFrecuencia, f, P1);
            xlim(app.UIAxesFrecuencia, [0, Fs/2]); 

            xlabel(app.UIAxesFrecuencia, 'Frecuencia (Hz)'); 
            ylabel(app.UIAxesFrecuencia, 'Amplitud |Y(f)|');
            title(app.UIAxesFrecuencia, 'Espectro de Amplitud (FFT)');
            grid(app.UIAxesFrecuencia, 'on');
            
            % Comprobar si la señal es silencio puro (evita errores)
            if all(y_plot == 0)
                cla(app.UIAxesSpectrogram); % Limpiar los ejes
                app.TextArea.Value = 'Análisis: La señal es silencio puro.';
                return;
            end

            % Dibujar el espectrograma en los ejes correctos
            try
                % Limpiamos los ejes del espectrograma
                cla(app.UIAxesSpectrogram);
                % 'yaxis' pone la frecuencia en el eje Y.
                % 'parent' le dice dónde dibujarlo.
                %256 muestras, 250 superposición para suavizado
                %256 numero de puntos de la FTT igual que la ventana
                spectrogram(y_plot, 256, 250, 256, Fs, 'yaxis', 'parent', app.UIAxesSpectrogram);
                app.UIAxesSpectrogram.FontSize = 8;
            catch ME
                uialert(app.UIFigure, ['Error al generar el espectrograma: ' ME.message], 'Error de Análisis');
                app.TextArea.Value = 'Error en el análisis.';
            end
        end


        % Genera la señal matemática basada en los parámetros
        function y = generateSignal(app, waveType, freq, amp, phaseDeg, duty, sym, p_rise, p_on, p_fall, Fs_user, Dur_user)
            
            Fs = Fs_user;    % Frecuencia de muestreo (Hz) la que quiera el usuario
            T = Dur_user;        % Duración (segundos)
            
            % Vector de tiempo: 0, 1/Fs, 2/Fs, ..., T - 1/Fs
            if Fs <= 0 || T <= 0
                 y = []; return; % Protección básica
            end
            t = 0 : 1/Fs : T - 1/Fs;
            
            % 1. Gestión del Desfase (Phase Shift)
            % Convertimos grados a radianes.
            % En la fórmula 'omega * t', sumamos la fase directamente.
            phaseRad = deg2rad(phaseDeg);
            
            % Argumento base para las funciones trigonométricas
            % Frecuencia angular: 2 * pi * f (omega)
            arg = 2 * pi * freq * t + phaseRad;
                        
            switch waveType
                case 'Senoidal'
                    % Seno puro: y = A * sin(2*pi*f*t)
                    y = amp * sin(arg);
                    
                case 'Cuadrada'
                    % Onda cuadrada: usa la función square(t, duty)
                    % duty va de 0 a 100
                    y = amp * square(arg, duty); 
                    
                case 'Triangular'
                    % Onda triangular: sawtooth(omega*t, width)
                    % width (simetría) va de 0 a 1
                    % 0.5 = Triángulo, 1 = Sierra, 0 = Sierra Invertida
                    y = amp * sawtooth(arg, sym);
                    
                case 'Diente de Sierra'
                    % Onda diente de sierra: sawtooth(omega*t), con width=0 (por defecto)
                    y = amp * sawtooth(arg);
                
                case 'Trapezoidal'

                    % interp1 falla si los tiempos son exactamente 0 (puntos duplicados en X).
                    % Forzamos que sean al menos un número minúsculo (epsilon).
                    epsilon = 1e-6; 
                    
                    % Si el valor es 0, lo cambiamos a 0.000001
                    if p_rise < epsilon, p_rise = epsilon; end
                    if p_on < epsilon,   p_on = epsilon; end
                    if p_fall < epsilon, p_fall = epsilon; end
                    % -------------------------------
                    
                    % 1. Crear un vector de tiempo "cíclico" normalizado de 0 a 1
                    % mod (t,1): si imaginamos t como una linea recta que crece 
                    % hasta infinito (0, 0.5, 1, 1.5, 2, 2.5...) la función 
                    % mod hace que al llegar al 1 volvamos al 0. Transforma la
                    % función lineal del tiempo en una función "diente de
                    % sierra".
                    t_cycle = mod(t * freq + (phaseDeg/360), 1);
                    
                    % 2. Definir los Puntos Clave del Trapecio (Eje X: Tiempo 0-1)
                    % Convertimos los porcentajes (0-100) a tanto por uno (0-1)
                    x_rise_end = p_rise / 100;
                    x_on_end   = (p_rise + p_on) / 100;
                    x_fall_end = (p_rise + p_on + p_fall) / 100;
                    
                    % Definimos los "hitos" del ciclo:
                    % Inicio -> FinSubida -> FinAlto -> FinBajada -> FinCiclo
                    x_points = [0, x_rise_end, x_on_end, x_fall_end, 1];

                    % 3. Definir los Valores de Amplitud (Eje Y: -1 a 1)
                    % Asumimos una señal bipolar (-1 a 1), luego se
                    % multiplica por la amplitud
                    % Inicio(-1) -> Subida(+1) -> Alto(+1) -> Bajada(-1) -> Fin(-1)
                    
                    % Nota: Si x_rise_end es 0 (subida instantánea), interp1 lo maneja bien.
                    y_points = [-1,  1,  1, -1, -1];
                    
                    % 4. Interpolar (Unir los puntos con interp1) 
                    % 'linear' hace líneas rectas entre los puntos.
                    y = interp1(x_points, y_points, t_cycle, 'linear');
                    
                    % 5. Aplicar la Amplitud final
                    y = y * amp;
                    
                otherwise
                    y = zeros(size(t));
            end
            
            % Aseguramos que la señal sea un vector columna (necesario para audioplayer/audiowrite)
            y = y'; 
        end
     
        % Aplica la operación matemática a la señal y a su Fs (si es necesario)
        function [y_proc, Fs_proc] = processSignal(app, y, Fs, operation, factor, dominio)
            
            y_proc = y;
            Fs_proc = Fs;

            switch operation
            
                case 'Inversion Temporal'
                    % Operación: Inversión Temporal (Solo afecta a la Data)
                    y_proc = flipud(y); 
                    app.TextArea.Value = 'Operación: Inversión Temporal aplicada.';
                    return; % Termina la función aquí

            end

            % --- Lógica de Compresión/Expansión 
            % 1. Determinar el FactorDeEscala
            
            FactorDeEscala = 1.0; % Por defecto, sin cambio.

            if strcmp(operation, 'Compresion')
                % El factor se aplica directamente multiplicando.
                % Ejemplo: factor=2 -> x2 Fs. Si factor=0.5 -> /2 Fs 
                FactorDeEscala = factor; % Compresión real (x2, x3, etc.)
            

            elseif strcmp(operation, 'Expansion')
                % El factor debe aplicarse dividiendo (o multiplicando por 1/factor).
                % Ejemplo: factor=2 -> /2 Fs. Si factor=0.5 -> x2 Fs (se convierte en compresión).
                FactorDeEscala = 1.0 / factor; % Expansión real (/2, /3, etc.)
               
            else
                % Si llega aquí, es una operación que no se maneja abajo (ej. 'Ninguna')
                app.TextArea.Value = 'Error: Operación no válida para Compresión/Expansión.';
                return;
            end
            
            
            % 2. Aplicar la lógica al Dominio seleccionado (usando FactorDeEscala)
            
            if strcmp(dominio, 'Tiempo')
                % DOMINIO DEL TIEMPO: Modificar la Frecuencia de Muestreo (Fs)
                Fs_proc = Fs * FactorDeEscala;
                app.TextArea.Value = [operation ' en Dominio del Tiempo (Factor Final x' num2str(FactorDeEscala) ') aplicada.'];

            elseif strcmp(dominio, 'Frecuencia')
                % DOMINIO DE LA FRECUENCIA: Implementación de Filtrado (solo funciona para expansión)
                Fs_proc = Fs/FactorDeEscala;
                app.TextArea.Value = [operation ' en Frecuencia aplicada.'];
                %CODIGO ANTIGUO (con diezmado e interpolación)
                %if FactorDeEscala < 1 % Es Expansión (FactorDeEscala es < 1, compresion de espectro)
                   
                    % % FactorDeEscala = 1.0 / FactorDeEscala;
                    % % P_exp = 100; 
                    % Q_exp = round(FactorDeEscala * 100);
                    % 
                    % % Asegurarnos de que Q no sea cero si el factor es muy pequeño
                    % if Q_exp == 0
                    %     Q_exp = 1; 
                    % end
                    % 
                    % try
                    %     % Aplicar resample (incluye interpolación y filtro anti-aliasing)
                    %     % resample (y,P,Q) cambia el número de muestras
                    %     % basándose en la fracción P/Q. P y Q deben de ser enteros 
                    %     % P factor de interpolación (añadir muestras)
                    %     % Q factor de diezmado (eliminamos muestras)
                    %     y_proc = resample(y, P_exp, Q_exp);
                    % 
                    %     app.TextArea.Value = [operation ' en Frecuencia (Resample x' num2str(P_exp/Q_exp) ') aplicada.'];
                    % 
                    % catch ME
                    %     uialert(app.UIFigure, ['Error en resample (Expansión): ' ME.message], 'Error de Procesado');
                    %     y_proc = []; % Devolver vacío si resample falla
                    %     return;
                    % end

                %elseif FactorDeEscala > 1 % Es Compresión (FactorDeEscala es > 1)

                    % FactorDeEscala = 1.0 / FactorDeEscala;
                    % 
                    % % Lógica correcta para COMPRESIÓN:
                    % P_comp = 100;
                    % Q_comp = round(FactorDeEscala * 100); % ej: 200
                    % 
                    % try
                    %     % Aplicar resample (diezmado)
                    %     % ej: resample(y, 100, 200) -> Comprime x2
                    %     y_proc = resample(y, P_comp, Q_comp); 
                    % 
                    %     app.TextArea.Value = [operation ' en Frecuencia (Resample x' num2str(P_comp/Q_comp) ') aplicada.'];
                    % 
                    % catch ME
                    %     uialert(app.UIFigure, ['Error en resample (Compresión): ' ME.message], 'Error de Procesado');
                    %     y_proc = []; % Devolver vacío si resample falla
                    %     return;
                    % end
     
                
                else % FactorDeEscala == 1
                    app.TextArea.Value = 'Factor 1.0; no se aplicó ningún cambio en Frecuencia.';
                    return;
                end
            
        end
        
        % Esta función se llamará cada 50ms (MARCADOR DE TIEMPO)
        function updatePlaybackMarker(app, player, event)
            % Comprobamos si el marcador todavía existe
            %isvalid para verificar que la linea todavia exista
            if ~isempty(app.PlaybackMarker) && isvalid(app.PlaybackMarker)
                % Obtenemos el tiempo actual del reproductor
                %player es el objeto de audioplayer que esta sonando
                %sampleRate= Fs
                currentTime = player.CurrentSample / player.SampleRate;

                % Movemos la línea (marcador) a esa posición
                app.PlaybackMarker.Value = currentTime;

                % Forzamos a la GUI a actualizarse
                %limitrate por si estamos pidiendo que se actualice más
                %rapido de lo que la pantalla puede mostrar
                drawnow('limitrate');
            end
        end

        % Esta función borra el marcador de la gráfica
        function clearPlaybackMarker(app)
            % Comprueba si la app sigue siendo válida Y si el marcador existe
            if isvalid(app) && ~isempty(app.PlaybackMarker) && isvalid(app.PlaybackMarker)
                delete(app.PlaybackMarker);
            end
            
            % Comprueba si la app sigue siendo válida antes de borrar la propiedad
            if isvalid(app)
                %aqui borramos la referencia en la memoria
                %para que cuando pregunte isempty sepa que no hay ninguna
                %línea activa
                app.PlaybackMarker = []; % Reseteamos la propiedad
            end
        end

        % Función para AÑADIR ruido
        function y_proc = addGaussianNoise(app, y, power)
            if power <= 0
                uialert(app.UIFigure, 'La potencia del ruido debe ser > 0.', 'Error de Parámetro');
                y_proc = []; % Devolvemos vacío si falla
                return;
            end
            
            % Generar ruido gaussiano (randn) y sumarlo a la señal
            noise = power * randn(size(y));
            y_proc = y + noise;
            
            % Importante: Normalizar si la señal excede los límites máximos permitidos (+1.0 o -1.0) 
            % para evitar 'clipping'(distorsión digital)
            if max(abs(y_proc)) > 1.0
                y_proc = y_proc / max(abs(y_proc));
                app.TextArea.Value = 'Aviso: Ruido aplicado y señal normalizada para evitar clipping.';
            else
                app.TextArea.Value = 'Ruido Gaussiano aplicado.';
            end
        end

        % Función para APLICAR FILTRO (Recuperación)
        function y_proc = applyLowPassFilter(app, y, Fs, Fc)
            % Fc = Frecuencia de Corte
            
            if Fc <= 0
                uialert(app.UIFigure, 'La frecuencia de corte debe ser > 0 Hz.', 'Error de Parámetro');
                y_proc = [];
                return;
            end
            
            % Frecuencia de Nyquist (límite máximo)
            Fn = Fs / 2;
            if Fc >= Fn
                uialert(app.UIFigure, ['La frec. de corte debe ser menor que Nyquist (' num2str(Fn) ' Hz).'], 'Error');
                y_proc = [];
                return;
            end
            
            % Normalizar la frecuencia de corte (0 a 1)
            Wn = Fc / Fn;
            orden = 6; % Orden del filtro (un buen valor por defecto)
            
            try
                % Diseñar un filtro Butterworth (FPB)
                [b, a] = butter(orden, Wn, 'low');
                
                % Aplicar el filtro a la señal
                y_proc = filter(b, a, y);
                app.TextArea.Value = ['Filtro Paso Bajo aplicado (Fc: ' num2str(Fc) ' Hz).'];
            catch ME
                uialert(app.UIFigure, ['Error al diseñar el filtro: ' ME.message], 'Error');
                y_proc = [];
            end
        end

        % Dibuja una señal procesada 
        function overlayProcessedPlot(app, y_proc, Fs_proc)
          % --- 1. GRÁFICA DE TIEMPO (ABAJO) ---
            cla(app.UIAxesTiempoProc); % Limpiamos el eje de abajo
            
            % Cálculo del vector de tiempo
            L_proc = length(y_proc);
            t_proc = (0:L_proc-1) / Fs_proc;
            
            % Gestión Mono/Estéreo
            if size(y_proc, 2) > 1
                y_plot_proc = y_proc(:, 1);
            else
                y_plot_proc = y_proc;
            end
            
            plot(app.UIAxesTiempoProc, t_proc, y_plot_proc, 'r-'); % Dibujamos en rojo
            xlabel(app.UIAxesTiempoProc, 'Tiempo (s)');
            ylabel(app.UIAxesTiempoProc, 'Amplitud (V)');
            title(app.UIAxesTiempoProc, 'Señal Procesada (Tiempo)');
            grid(app.UIAxesTiempoProc, 'on');
            
            % Ajustar límites X para que coincidan con la duración
            xlim(app.UIAxesTiempoProc, [0, t_proc(end)]);

            % --- 2. GRÁFICA DE FRECUENCIA FFT (ABAJO) ---
            cla(app.UIAxesFrecuenciaProc); % Limpiamos el eje de abajo
            
            if length(y_plot_proc) >= 2
                L_fft = length(y_plot_proc);
                Y_proc = fft(y_plot_proc);
                P2_proc = abs(Y_proc/L_fft);
                P1_proc = P2_proc(1:floor(L_fft/2)+1);
                f_proc = Fs_proc*(0:(floor(L_fft/2)))/L_fft;
                
                plot(app.UIAxesFrecuenciaProc, f_proc, P1_proc, 'r-');
                xlabel(app.UIAxesFrecuenciaProc, 'Frecuencia (Hz)');
                ylabel(app.UIAxesFrecuenciaProc, 'Magnitud');
                title(app.UIAxesFrecuenciaProc, 'Espectro Procesado (FFT)');
                grid(app.UIAxesFrecuenciaProc, 'on');
                xlim(app.UIAxesFrecuenciaProc, [0, Fs_proc/2]);
            end

            
            % Evitar error si es silencio absoluto
            if all(y_plot_proc == 0)
                 title(app.UIAxesSpectrogramProc, 'Silencio');
            else
                try
                   % --- 1. GRÁFICA DE TIEMPO (ABAJO) ---
            cla(app.UIAxesTiempoProc); % Limpiamos el eje de abajo
            
            % Cálculo del vector de tiempo
            L_proc = length(y_proc);
            t_proc = (0:L_proc-1) / Fs_proc;
            
            % Gestión Mono/Estéreo
            if size(y_proc, 2) > 1
                y_plot_proc = y_proc(:, 1);
            else
                y_plot_proc = y_proc;
            end
            
            plot(app.UIAxesTiempoProc, t_proc, y_plot_proc, 'r-'); % Dibujamos en rojo
            xlabel(app.UIAxesTiempoProc, 'Tiempo (s)');
            ylabel(app.UIAxesTiempoProc, 'Amplitud (V)');
            title(app.UIAxesTiempoProc, 'Señal Procesada (Tiempo)');
            grid(app.UIAxesTiempoProc, 'on');
            
            % Ajustar límites X para que coincidan con la duración
            xlim(app.UIAxesTiempoProc, [0, t_proc(end)]);

            % --- 2. GRÁFICA DE FRECUENCIA FFT (ABAJO) ---
            cla(app.UIAxesFrecuenciaProc); % Limpiamos el eje de abajo
            
            if length(y_plot_proc) >= 2
                L_fft = length(y_plot_proc);
                Y_proc = fft(y_plot_proc);
                P2_proc = abs(Y_proc/L_fft);
                P1_proc = P2_proc(1:floor(L_fft/2)+1);
                f_proc = Fs_proc*(0:(floor(L_fft/2)))/L_fft;
                
                plot(app.UIAxesFrecuenciaProc, f_proc, P1_proc, 'r-');
                xlabel(app.UIAxesFrecuenciaProc, 'Frecuencia (Hz)');
                title(app.UIAxesFrecuenciaProc, 'Espectro Procesado (FFT)');
                grid(app.UIAxesFrecuenciaProc, 'on');
                xlim(app.UIAxesFrecuenciaProc, [0, Fs_proc/2]);
            end

            % --- 3. ESPECTROGRAMA (ABAJO) ---
            % Ahora también dibujamos el espectrograma en el panel inferior
            cla(app.UIAxesSpectrogramProc);

            % Comprobar si la señal es silencio puro (evita errores)
            if all(y_plot_proc == 0)
                cla(app.UIAxesSpectrogramProc); % Limpiar los ejes
                app.TextArea.Value = 'Análisis: La señal es silencio puro.';
                return;
            end

            % Dibujar el espectrograma en los ejes correctos
            try
                % 'yaxis' pone la frecuencia en el eje Y.
                % 'parent' le dice dónde dibujarlo.
                %256 muestras, 250 superposición para suavizado
                %256 numero de puntos de la FTT igual que la ventana
                spectrogram(y_plot_proc, 256, 250, 256, Fs_proc, 'yaxis', 'parent', app.UIAxesSpectrogramProc);
                app.UIAxesSpectrogramProc.FontSize = 8;
                xlim(app.UIAxesSpectrogramProc, [0, t_proc(end)]);
            catch ME
                uialert(app.UIFigure, ['Error al generar el espectrograma: ' ME.message], 'Error de Análisis');
                app.TextArea.Value = 'Error en el análisis.';
            end
                end
            end
        end
    
        % --- FUNCIONES DE MODULACIÓN Y DEMODULACIÓN ---

        % 1. Modulación AM (DSB-TC: Doble Banda Lateral con Portadora)
        function y_mod = performAMMod(app, y, Fs, Fc)
            
            % para generar la portadora necesitamos una variable t
            % la señal de audio y, es un vector de valores que representan
            % muestras pero no se sabe en que segundos ocurren
            % 0:length(y)-1 creamos los indices y al dividir por Fs
            % (muestras/seg) obtenemos los segundos correspondientes a cada
            % muestra
            t = (0:length(y)-1)' / Fs; % Vector de tiempo
            
            % Normalizamos la señal mensaje para evitar sobremodulación
            % Queremos que |y| < 1. Lo dejamos en 0.8 por seguridad.
            if max(abs(y)) > 0
                y_norm = y / max(abs(y)) * 0.8; 
            else
                y_norm = y;
            end
            
            % Fórmula AM: (1 + m*x(t)) * cos(2*pi*fc*t)
            % Asumimos índice de modulación m=1 (o incluido en la normalización)
            carrier = cos(2 * pi * Fc * t);
            y_mod = (1 + y_norm) .* carrier;
            
            app.TextArea.Value = ['Modulación AM realizada a ' num2str(Fc) ' Hz.'];
        end

        % 2. Demodulación AM (Detector Coherente + Filtro)
        function y_demod = performAMDemod(app, y, Fs, Fc)
            t = (0:length(y)-1)' / Fs;
            
            % Paso 1: Multiplicar por la portadora (mezclado)
            % modulacion AM "sube" la señal a una Fc, queremos bajarla
            % ej: multiplicar cos(Fc)xcos(Fc) -> 2 partes
            % suma: señal al doble de Fc que eliminamos con filtro
            % resta: señal en 0
            demod_raw = y .* cos(2 * pi * Fc * t);
            
            % Paso 2: Filtro Paso Bajo para recuperar la envolvente
            % Reutilizamos tu función de filtro existente. 
            % Fc del filtro debe ser la de audio (ej. 4000 Hz), NO la portadora.
            FrecuenciaAudio = 4000; % 4kHz estándar para voz
            y_demod = applyLowPassFilter(app, demod_raw, Fs, FrecuenciaAudio);
            
            % Eliminar la componente DC (el "1" que sumamos al modular)
            % mean: media
            y_demod = y_demod - mean(y_demod);
            
            app.TextArea.Value = ['Demodulación AM realizada (Portadora ' num2str(Fc) ' Hz).'];
        end

        % 3. Modulación FM
        function y_mod = performFMMod(app, y, Fs, Fc)
            t = (0:length(y)-1)' / Fs;
            
            % Parámetro de desviación de frecuencia (kf)
            kf = 50; % Sensibilidad de frecuencia
            
            % Fórmula FM: cos(2*pi*fc*t + 2*pi*kf * integral(mensaje))
            % Usamos cumsum (cumulativ sum) como integral (va sumando muestras)
            % al dividir por s obtenemos tiempo (ud)
            integral_y = cumsum(y) / Fs; 
            
            phase = 2 * pi * Fc * t + 2 * pi * kf * integral_y;
            y_mod = cos(phase);
            
            app.TextArea.Value = ['Modulación FM realizada a ' num2str(Fc) ' Hz.'];
        end

        % 4. Demodulación FM
        function y_demod = performFMDemod(app, y, Fs, Fc)
            % Usamos la Transformada de Hilbert para extraer la fase instantánea
            try
                % Convierte la señal real y (que solo tiene amplitud en el 
                % tiempo) en una Señal Analítica Compleja z  
                % z(t) = y(t) + j*y(t)$.
                z = hilbert(y);
                
                % Fase instantánea 
                % Extraemos el angulo del vector z
                % angle(z) calcula la arcotangente
                % Cuando el ángulo llega a pi y salta a -pi, unwrap
                % detecta ese salto y le suma 2pi obteniéndo la fase total
                % acumulada
                
                inst_phase = unwrap(angle(z));
                
                % La frecuencia es la derivada de la fase
                % Dividimos por 2*pi  y multiplicamos por Fs para pasar de
                % radianes a Hz
                inst_freq = diff(inst_phase) * Fs / (2 * pi);
                
                % Recuperar el mensaje: Restar la portadora y filtrar
                % (La derivada de 2*pi*Fc*t es Fc, así que restamos Fc)
                raw_msg = inst_freq - Fc;
                
                % Añadimos un 0 al final porque 'diff' reduce el tamaño en 1
                raw_msg = [raw_msg; 0]; 
                
                % Filtramos ruido de alta frecuencia
                y_demod = applyLowPassFilter(app, raw_msg, Fs, 4000);
                
                app.TextArea.Value = ['Demodulación FM realizada (Portadora ' num2str(Fc) ' Hz).'];
                
            catch
                uialert(app.UIFigure, 'Error en Demodulación FM. Requiere Signal Processing Toolbox.', 'Error');
                y_demod = [];
            end
        end

       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            %BOTON DE CARGAR
            %abre diálogo de selleción de archivo uigetfile ( filtro wav )
            [filename, pathname] = uigetfile( ...
                { '*.wav;*.mp3;*.m4a;*.flac;*.au;*.ogg', 'Archivos de Audio (*.wav, *.mp3, etc.)'; ...
                  '*.*', 'Todos los archivos (*.*)'}, ...
                  'Seleccionar archivo de audio');            
            % Si el usuario no cancela
            if filename ~= 0
                fullfilepath = fullfile(pathname, filename); %combina ruta y nombre del archivo
              
                try %por si hay un archivo corrupto (por ejemplo)

                    [y, Fs] = audioread(fullfilepath);
                    
                    % Guardar los datos de la nueva señal
                    newSignal.Name = filename;
                    newSignal.Data = y;
                    newSignal.Fs = Fs;
                    
                    %end+1 para añadirla al final del array
                    %añadimos la nueva señal
                    app.SignalData(end+1) = newSignal;
                    
                    % Actualizar la lista
                    updateListBox(app);
                    
                    % Seleccionar la nueva señal en la listbox
                    app.Archivos.Value = filename;
                    
                    % Actualizar las gráficas
                    updatePlots(app);
                    
                    %mensajes 
                    app.TextArea.Value = ['Cargado: ' filename];

                %ME contiene la ifnormacion detallada sobre porque falló
                catch ME %si audioread falla
                    uialert(app.UIFigure, ['Error al leer el archivo: ' ME.message], 'Error de Carga');
                    app.TextArea.Value = 'Error al cargar archivo.';
                end
            end
  
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %archivo seleccionado
            selectedNames = app.Archivos.Value;

            if isempty(selectedNames)
                return; % No hay nada seleccionado
            end
            
            % Encontrar
            % Usamos ismember en lugar de strcmp, porque selectedNames es
            % un cell array
            % Encuentra qué señales de la memoria coinciden con CUALQUIERA de los nombres seleccionados
            idx = ismember({app.SignalData.Name}, selectedNames);
            %borrar (cadena vacía)
            app.SignalData(idx) = [];
            
            % Actualizar la lista
            updateListBox(app);
            
            % Limpiar gráficas
            cla(app.UIAxesTiempo);
            cla(app.UIAxesFrecuencia);
            
            numeroArchivos=length(selectedNames);

            if numeroArchivos == 1
                app.TextArea.Value = ['Eliminado: ' selectedNames{1}];
            else
                stringNumeros=num2str(numeroArchivos);
                app.TextArea.Value = ['Eliminados ' stringNumeros ' archivos.'];
            end
          
   
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
           
            %Array de estructura vacío
            app.SignalData = struct('Name', {}, 'Data', {}, 'Fs', {});
            
            %actualizamos
            updateListBox(app);

            %limpiamos
            cla(app.UIAxesTiempo);
            cla(app.UIAxesFrecuencia);
            cla(app.UIAxesTiempoProc);
            cla(app.UIAxesFrecuenciaProc);
            cla(app.UIAxesSpectrogram);
            cla(app.UIAxesSpectrogramProc);
            %vaciamos memoria temporal
            app.ProcessedData = []; 
            app.ProcessedFs = []; 
            app.TextArea.Value = 'Proyecto limpiado.';
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            [y, Fs] = getSelectedSignal(app);
            
            if isempty(y)
                app.TextArea.Value = 'Error: No hay señal seleccionada para guardar.';
                return;
            end
            
            % Abre el diálogo para elegir la ubicación y nombre del archivo
            % uiputfile
            [filename, pathname] = uiputfile({'*.wav', 'Archivos WAVE (*.wav)'}, 'Guardar señal como...');
            
            if filename ~= 0 % Si el usuario no cancela
                fullfilepath = fullfile(pathname, filename);

                try
                    % Guarda el archivo de audio
                    audiowrite(fullfilepath, y, Fs);

                    %vector índice (comparamos el nombre de la señal con el de archivos)
                    idx = strcmp({app.SignalData.Name}, app.Archivos.Value);
                    %renombramos el fichero en el vector estructura
                    app.SignalData(idx).Name = filename;
                    %actualizamos
                    updateListBox(app);
                    app.Archivos.Value = filename;
                    
                    app.TextArea.Value = ['Señal guardada con éxito en: ' fullfilepath];
                catch ME
                    uialert(app.UIFigure, ['Error al guardar el archivo: ' ME.message], 'Error de Guardado');
                    app.TextArea.Value = 'Error al guardar archivo.';
                end
            end
        end

        % Button pushed function: Button_3
        function nuevoButtonPushed(app, event)

            % 1. Generar nombre único para el nuevo archivo de proyecto (Sin_Título_X.wav)
            %para buscar cuantas señales ya tienen la etiqueta Sin_Título
            numSilencios = sum(contains({app.SignalData.Name}, 'Sin_Título'));
            filename = ['Sin_Título_', num2str(numSilencios + 1), '.wav'];
            
            % 2. Crear una señal de 1 segundo de silencio
            % app.GeneratedFs se asume como 44100 Hz por defecto (definido en properties)
            y_silencio = zeros(app.GeneratedFs, 1); 
            
            % 3. Guardar la señal de silencio en el almacén (Memoria)
            % Escribimos todos los parámetros
            newSignal.Name = filename;
            newSignal.Data = y_silencio;
            newSignal.Fs = app.GeneratedFs;
            app.SignalData(end+1) = newSignal;
            
            % 4. Actualizar la GUI
            updateListBox(app);
            app.Archivos.Value = filename; % Selecciona el nuevo archivo
            updatePlots(app);
            
            app.TextArea.Value = ['Nuevo proyecto creado: ' filename];
        
        end

        % Button pushed function: ActualizarButton
        function ActualizarButtonPushed(app, event)
            % Llama a la misma función que la lista
            updatePlots(app);
            cla(app.UIAxesTiempoProc);      % Limpiar Eje Tiempo
            cla(app.UIAxesFrecuenciaProc);  % Limpiar Eje Frecuencia
            cla(app.UIAxesSpectrogramProc);  % Limpiar Espectrograma
            app.ProcessedData = []; 
            app.ProcessedFs = [];  
            app.TextArea.Value = 'Gráficas actualizadas.';
        end

        % Button pushed function: InicioButton
        function InicioButtonPushed(app, event)
            [y, Fs] = getSelectedSignal(app); %coger señal
            
            if ~isempty(y)
                % Detener cualquier reproducción anterior
                if ~isempty(app.AudioPlayer) && isplaying(app.AudioPlayer)
                    stop(app.AudioPlayer);
                end
                
                %Borrar el marcador anterior (líea roja)
                clearPlaybackMarker(app);

                LIMIT_MIN_FS = 1000; % Límite seguro estándar (8 kHz)

                if Fs < LIMIT_MIN_FS
                 % Si la frecuencia es demasiado baja, NO intentamos reproducir.
                 % Mostramos un aviso al usuario explicando por qué.
        
                    mensaje = sprintf('No se puede reproducir: La Frecuencia de Muestreo (%d Hz) es demasiado baja para la tarjeta de sonido.', round(Fs));
                    app.TextArea.Value = mensaje;
                    uialert(app.UIFigure, [mensaje newline newline 'El efecto de Aliasing es visible en la gráfica, pero el hardware no admite reproducción a esta velocidad.'],'Error de Hardware');
                    return; % Nos salimos de la función
                end

                %Crear el nuevo reproductor
                %crea un objeto audioplayer
                app.AudioPlayer = audioplayer(y, Fs);
                % Cada cuánto actualiza (0.05s)
                app.AudioPlayer.TimerPeriod = 0.05;

                % Usamos una función anónima para pasar 'app'
                %updatePlaybackMarker mueve la línea roja
                %TimerFcn cada 50ms ejecuta la funcion de despues del =
                %hemos creado un reloj con TImerPeriod; cada periodo se
                %ejecuta lo que diga TimerFcn

                app.AudioPlayer.TimerFcn = @(player, event) updatePlaybackMarker(app, player, event);
                
                % CREAR EL MARCADOR
                % para no borrar la gráfica de tiempo
                hold(app.UIAxesTiempo, 'on');
                
                % Creamos una línea vertical (xline) en t=0
                %xline(eje, t, 'Color', 'r', 'LineWidth', grosor);
                app.PlaybackMarker = xline(app.UIAxesTiempo, 0, 'Color', 'r', 'LineWidth', 1.5);
                
                %para volver al modo normal
                hold(app.UIAxesTiempo, 'off');
                play(app.AudioPlayer);
                      
            else
                app.TextArea.Value = 'Nada seleccionado para reproducir.';
            end
        end

        % Button pushed function: InicioButton_2
        function InicioButton_2Pushed(app, event)
            % 1. LEER DE LA MEMORIA TEMPORAL
            y = app.ProcessedData;
            Fs = app.ProcessedFs;
            
            % Validación:
            if isempty(y) || isempty(Fs)
                app.TextArea.Value = 'Error: No hay ninguna señal procesada disponible.';
                uialert(app.UIFigure, 'Primero debes aplicar un efecto (Aliasing, Filtro...) para tener una señal procesada.', 'Nada que reproducir');
                return;
            end
            
            % 2. Detener reproducciones anteriores
            if ~isempty(app.AudioPlayer) && isplaying(app.AudioPlayer)
                stop(app.AudioPlayer);
            end
            
            % 3. Borrar marcador anterior
            clearPlaybackMarker(app);
            
            % 4. Validar Hardware 
            LIMIT_MIN_FS = 1000; 
            
           
            if Fs < LIMIT_MIN_FS
                 % Si la frecuencia es demasiado baja, NO intentamos reproducir.
                 % Mostramos un aviso al usuario explicando por qué.
        
                    mensaje = sprintf('No se puede reproducir: La Frecuencia de Muestreo (%d Hz) es demasiado baja para la tarjeta de sonido.', round(Fs));
                    app.TextArea.Value = mensaje;
                    uialert(app.UIFigure, [mensaje newline newline 'El efecto de Aliasing es visible en la gráfica, pero el hardware no admite reproducción a esta velocidad.'],'Error de Hardware');
                    return; % Nos salimos de la función
            else
                 y_play = y; Fs_play = Fs;
            end
           

            % 5. Crear Reproductor
            try
                app.AudioPlayer = audioplayer(y_play, Fs_play);
                app.AudioPlayer.TimerPeriod = 0.05;
                app.AudioPlayer.TimerFcn = @(player, event) updatePlaybackMarker(app, player, event);
                
                % 6. DIBUJAR MARCADOR EN LA GRÁFICA DE ABAJO (UIAxesTiempoProc)
                hold(app.UIAxesTiempoProc, 'on');
                % Dibujamos la línea roja en el eje de abajo
                app.PlaybackMarker = xline(app.UIAxesTiempoProc, 0, 'Color', 'r', 'LineWidth', 1.5);
                hold(app.UIAxesTiempoProc, 'off');
                
                play(app.AudioPlayer);
                
            catch ME
                app.TextArea.Value = 'Error de Audio.';
                uialert(app.UIFigure, ME.message, 'Error');
            end
        end

        % Button pushed function: PlayPauseButton
        function PlayPauseButtonPushed(app, event)
            if ~isempty(app.AudioPlayer)
                if isplaying(app.AudioPlayer)
                    pause(app.AudioPlayer);
                    app.TextArea.Value = 'Pausado.';
                elseif ~isplaying(app.AudioPlayer)
                    % Si ya está pausado, lo reanuda
                    resume(app.AudioPlayer);
                    app.TextArea.Value = 'Reanudando...';
                end
            end
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)

            % --- 1. VALIDACIÓN INICIAL ---            
            %PRIMERO GENERAMOS LA SEÑAL SOLO SI HAY UN ARCHIVO SELECCIONADO
            % Obtener la señal actualmente seleccionada (la que se va a sobrescribir)
            [y_original, ~] = getSelectedSignal(app); 

           % 2. VALIDACIÓN DE SELECCIÓN
            if isempty(y_original)
                % Si 'y_original' está vacío, es porque:
                % a) No se seleccionó nada.
                % b) Se seleccionaron >1 (getSelectedSignal ya mostró la alerta).
                
                % Si fue la opción (a), mostramos este mensaje:
                if isempty(app.Archivos.Value)
                   app.TextArea.Value = 'Error: Ningún archivo seleccionado. Use "+" para crear uno.';
                  app.RecordingStatusLabel.Value = 'ERROR';
                end
                return; % Salir de la función
            end
            selectedName = app.Archivos.Value{1};

            % --- 2. LÓGICA DEL TOGGLE (Grabar / Detener) ---
            % Si llegamos aquí, SÍ hay un archivo seleccionado (selectedName)
            
            if isempty(app.AudioRecorder) || ~isrecording(app.AudioRecorder)
                % INICIAR GRABACIÓN
                % Parámetros estándar: 44.1 kHz, 16 bits, Mono
                Fs = 44100;
                nbits = 16;
                channels = 1; 
                
                % Crear el objeto grabador
                app.AudioRecorder = audiorecorder(Fs, nbits, channels);
                
                % Iniciar la grabación
                record(app.AudioRecorder);
                
                % Actualizar GUI
                app.RecordingStatusLabel.Value = 'GRABANDO...';
                app.RecordButton.Text = 'Detener';
                app.TextArea.Value = ['Grabación iniciada (sobreescribiendo ' selectedName ')...'];
                
            else
                % DETENER GRABACIÓN
                stop(app.AudioRecorder);
                
                % Obtener los datos grabados
                y_grabado = getaudiodata(app.AudioRecorder);
                Fs = app.AudioRecorder.SampleRate;
                
                % Generar nombre único para la grabación
                % (Sobrescribimos el nombre, ej: "Sin_Título_1.wav" -> "Grabación_1.wav")
                numGrabaciones = sum(contains({app.SignalData.Name}, 'Grabación'));
                filename = ['Grabación_', num2str(numGrabaciones + 1), '.wav'];
                
                % --- LÓGICA DE SOBRESCRITURA (Tu código) ---
                % Busca la señal seleccionada (la que tenía el nombre 'selectedName')
                idx = strcmp({app.SignalData.Name}, selectedName);

                % Sobrescribe los datos y el nombre de la señal seleccionada
                app.SignalData(idx).Name = filename; % <-- Renombra
                app.SignalData(idx).Data = y_grabado; % <-- Sobrescribe datos
                app.SignalData(idx).Fs = Fs;
                % --- FIN LÓGICA DE SOBRESCRITURA ---
                
                % Actualizar GUI
                app.RecordingStatusLabel.Value = 'LISTO';
                app.RecordButton.Text = 'Grabar';
                updateListBox(app);
                app.Archivos.Value = filename; % Selecciona el nuevo nombre
                updatePlots(app);
                app.TextArea.Value = ['Grabación guardada como: ' filename];
            end
        end

        % Button pushed function: PotenciamediaButton
        function PotenciamediaButtonPushed(app, event)
            % 1. Obtener la señal seleccionada
            [y, Fs] = getSelectedSignal(app);
            
            if isempty(y)
                uialert(app.UIFigure, 'No hay ninguna señal seleccionada.', 'Error');
                app.TextPotenciamedia.Value = 'Error';
                return;
            end
            
            % 2. Usar solo un canal (si es estéreo)
            if size(y, 2) > 1
                y_plot = y(:, 1);
            else
                y_plot = y;
            end
            potencia_media = mean(y_plot .^ 2);
            % Convertir a dBFS
            if potencia_media < 1e-10
                potencia_dBFS = -Inf;
            else
                potencia_dBFS = 10 * log10(potencia_media / 1.0);
            end
    

            %para poder imprimir dos decimales solo de un float usamos
            %sprintf
            app.TextPotenciamedia.Value = sprintf('%.2f dBFS', potencia_dBFS);
        
        end

        % Button pushed function: NiveldepresinsonoraButton
        function NiveldepresinsonoraButtonPushed(app, event)
            % 1. Obtener la señal seleccionada
            [y, Fs] = getSelectedSignal(app);
            
            if isempty(y)
                app.TextPresion.Value = 'Error';
                return;
            end
            
            % 2. Usar solo un canal (si es estéreo)
            if size(y, 2) > 1
                y_plot = y(:, 1);
            else
                y_plot = y;
            end
            
            % 3. Calcular la potencia media de la señal
            potencia_media = mean(y_plot .^ 2);
            
            % 4. Convertir a dBFS (Decibelios relativos a la escala completa)
            if potencia_media < 1e-10 % Evitar log(0)
                dBFS = -Inf;
            else
                % La potencia de referencia para escala completa es 1.0
                dBFS = 10 * log10(potencia_media / 1.0); 
            end

            % 5. ESTIAMCIÓN DE SPL
            % 
            % 94 dB SPL (un punto de calibración estándar).
            SPL_CALIBRATION_POINT = 94.0;
            
            % El SPL estimado es nuestro punto de calibración más el nivel en dBFS
            dB_SPL_estimado = SPL_CALIBRATION_POINT + dBFS;
            
            % 6. Mostrar el resultado
            if isinf(dB_SPL_estimado)
                 app.TextPresion.Value = 'Silencio';
            else
                 app.TextPresion.Value = sprintf('%.2f dB SPL (Est.)', dB_SPL_estimado);
            end
        end

        % Selection changed function: OperacionesElementalesButtonGroup
        function OperacionesElementalesButtonGroupSelectionChanged(app, event)
            %event contiene la información sobre el cambio
            %newValue es una referencia al botón que acaba de ser
            %seleeccionado
            % 1. Almacena la operación seleccionada (el texto del botón)
            app.SelectedOperation = event.NewValue.Text;
            
            % 2. Control dinámico del Panel
            if strcmp(app.SelectedOperation, 'Inversion Temporal')
                % Inversión no necesita factor, oculta el panel
                %app.OpcionesPanel.Visible = 'off';
                app.OpcionesPanel.Visible = 'on';
                app.TextArea.Value = 'Operación seleccionada: Inversión Temporal.';
            else
                % Compresión/Expansión sí necesita factor, muestra el panel
                app.OpcionesPanel.Visible = 'on';
                app.TextArea.Value = ['Operación seleccionada: ' app.SelectedOperation '. Introduce el Factor.'];

            end


        end

        % Button pushed function: GenerarSealButton
        function GenerarSealButtonPushed(app, event)
            % PRIMERO GENERAMOS LA SEÑAL SOLO SI HAY UN ARCHIVO SELECCIONADO
            % Obtener la señal actualmente seleccionada (la que se va a sobrescribir)
            [y_original, ~] = getSelectedSignal(app); 

            % VALIDACIÓN DE SELECCIÓN
            if isempty(y_original)
                % Si 'y_original' está vacío, es porque:
                % a) No se seleccionó nada.
                % b) Se seleccionaron >1 (getSelectedSignal ya mostró la alerta).
                
                % Si fue la opción (a), mostramos este mensaje:
                if isempty(app.Archivos.Value)
                   app.TextArea.Value = 'Error: Ningún archivo seleccionado. Use "+" para crear uno.';
                   uialert(app.UIFigure, 'Selecciona una señal para procesar.', 'Error');

                end
                return; % Salir de la función
            end
            selectedName = app.Archivos.Value{1};

            %GENERAMOS LA SEÑAL
            % 1. Obtener parámetros de la GUI
            waveType = app.FormaOnda.Value;           % Valor del Dropdown (ej: 'Senoidal (Seno)')
            freq = app.FrecuenciaGenerada.Value;      % Valor del campo de texto de Frecuencia (Hz)
            amp = app.AmplitudGenerada.Value;         % Valor del campo de texto de Amplitud (0-1)
            phase = app.DesfasegradosEditField.Value;

            newFs = app.FrecMuestreoEditField.Value;
            newDur = app.DuracionEditField.Value;

            %validar campos
            if isempty(newFs) || newFs < 256
                uialert(app.UIFigure, 'La Frecuencia de Muestreo debe ser mayor a 256 Hz.', 'Error');
                return;
            end
            if isempty(newDur) || newDur <= 0
                uialert(app.UIFigure, 'La duración debe ser positiva.', 'Error');
                return;
            end

            if isempty(phase), phase = 0; end
            
            duty = 50;  % Por defecto para Cuadrada
            sym = 0.5;  % Por defecto para Triangular
            p_rise = 0; p_on = 0; p_fall = 0; % Por defecto para trapezoidal

            % Leemos solo si el panel está visible o si la onda lo requiere
            if strcmp(waveType, 'Cuadrada')
                duty = app.DutyCycleEditField.Value;
                if isempty(duty) || duty < 0 || duty > 100
                    uialert(app.UIFigure, 'El Duty Cycle debe ser 0-100%.', 'Error'); return;
                end
            elseif strcmp(waveType, 'Triangular')
                sym = app.SimetriaEditField.Value;
                if isempty(sym) || sym < 0 || sym > 1
                    uialert(app.UIFigure, 'La Simetría debe ser 0-1.', 'Error'); return;
                end
            elseif strcmp(waveType, 'Trapezoidal')
                % Asumiendo que tus campos se llaman así (corrígelo si tienen otro nombre)
                p_rise = app.TiempoSubidaEditField.Value; 
                p_on   = app.TiempoAltoEditField.Value;
                p_fall = app.TiempoBajadaEditField.Value;
                
                % Asegurarnos de que no están vacíos
                if isempty(p_rise), p_rise=0; end
                if isempty(p_on), p_on=0; end
                if isempty(p_fall), p_fall=0; end
                
                % Validación 1: No pueden ser negativos
                if p_rise < 0 || p_on < 0 || p_fall < 0
                     uialert(app.UIFigure, 'Los porcentajes deben ser positivos.', 'Error'); return;
                end
                
                % Validación 2: La suma no puede superar el 100%
                if (p_rise + p_on + p_fall) > 100
                     uialert(app.UIFigure, 'La suma de porcentajes no puede superar el 100%.', 'Error'); return;
                end
            end
          
            % 2. Validación
            if isempty(freq) || isempty(amp) || freq <= 0 || amp <= 0
                uialert(app.UIFigure, 'Introduce una Frecuencia y Amplitud válidas y positivas.', 'Error de Parámetro');
                return;
            end

            % Nota de seguridad: limite de amplitud a 1.0 
            % máximo para señales normalizadas double, para evitar
            % distorsión
            if amp > 1.0 
                amp = 1.0;
                app.AmplitudGenerada.Value = 1.0; % Avisamos al usuario que se ha ajustado al máximo
            end


            % 3. Generar la señal (llama a la función auxiliar generateSignal)
            try
                y_generada = generateSignal(app, waveType, freq, amp, phase, duty, sym, p_rise, p_on, p_fall, newFs, newDur);
            catch ME
                uialert(app.UIFigure, ['Error: ' ME.message], 'Error');
                return;
            end

            % 4. Asignar nombre y almacenar en SignalData
            % construimos el nombre del fichero
            % Definimos la base del nombre (Ej: 'Senoidal_30Hz')
            baseName = [waveType, '_', num2str(freq), 'Hz'];
            
            % Buscamos en la memoria cuántos archivos empiezan ya por ese nombre
            names = {app.SignalData.Name};
            numExistentes = sum(startsWith(names, baseName, 'IgnoreCase', true));

            % Creamos el nombre final añadiendo el número siguiente (Ej: '_1.wav')
            filename = [baseName, '_', num2str(numExistentes + 1), '.wav'];
             
            %Busca la señal seleccionada en la memoria para SOBREESCRIBIR
            %devuelve el índice (la posición)
            idx = strcmp({app.SignalData.Name}, app.Archivos.Value);
            
            % Sobrescribe los campos Data, Fs y Name del elemento seleccionado
            app.SignalData(idx).Name = filename; 
            app.SignalData(idx).Data = y_generada;
            app.SignalData(idx).Fs = newFs;


            % 5. Actualizar la GUI
            % Asegura que la nueva señal aparece en la listbox, se selecciona y se dibuja
            updateListBox(app);              
            app.Archivos.Value = filename; 
            updatePlots(app);               
            
            app.TextArea.Value = ['Señal generada: ' filename];
           
        
        end

        % Button pushed function: CombinarButton
        function CombinarButtonPushed(app, event)
            % 1. Obtener los nombres de las señales seleccionadas
            selectedNames = app.Archivos.Value;
            
            % 2. Validación
            if length(selectedNames) < 2
                uialert(app.UIFigure, 'Seleccione al menos dos señales para combinar.', 'Error de Selección');
                return;
            end
            
            app.TextArea.Value = 'Combinando señales...';
            drawnow; % Forzar actualización del mensaje

            % 3. Coger la primera señal como base
            try
                %compara el nombre de la señal con la seleccionada
                %obtenemos el indice de la estructura
                idx_base = strcmp({app.SignalData.Name}, selectedNames{1});
                %cogemos la información sobre la señal de base
                baseSignal = app.SignalData(find(idx_base, 1));
                
                % Usamos la Fs más alta como Fs objetivo
                Fs_target = baseSignal.Fs;
                
                % Averiguar la Fs más alta de todas las seleccionadas
                %rescorremos todas las seleccionadas
                for i = 2:length(selectedNames)
                    idx_curr = strcmp({app.SignalData.Name}, selectedNames{i});
                    currSignal = app.SignalData(find(idx_curr, 1));
                    if currSignal.Fs > Fs_target
                        Fs_target = currSignal.Fs;
                    end
                end
                
                % Volver a procesar la señal base si no era la Fs más alta
                if baseSignal.Fs < Fs_target
                    %convierte los datos de la señal desde su Fs original a
                    %la nueva fs objetivo
                    y_final = resample(baseSignal.Data, Fs_target, baseSignal.Fs);
                else
                    y_final = baseSignal.Data;
                end
                
                % --- Bucle para sumar las demás señales ---
                %empezamos en el segundo elemento de la lista, porque el
                %primero ya lo usamos como base
                for i = 2:length(selectedNames)
                    idx_current = strcmp({app.SignalData.Name}, selectedNames{i});
                    currentSignal = app.SignalData(find(idx_current, 1));
                    
                    %cargamos los datos de la siguiente señal y su fs
                    y_current = currentSignal.Data;
                    Fs_current = currentSignal.Fs;
                    
                    % 4. Remuestrear si Fs no coincide
                 
                    if Fs_current ~= Fs_target
                        app.TextArea.Value = ['Remuestreando ' currentSignal.Name '...'];
                        drawnow;
                        %resample aplica filtro antialiasing
                        y_current = resample(y_current, Fs_target, Fs_current);
                    end
                    
                    % 5.Forzar a mono si hay mezcla
                    if size(y_final, 2) ~= size(y_current, 2)
                        app.TextArea.Value = 'Aviso: Mezclando a mono.';
                        drawnow;
                        if size(y_final, 2) > 1
                            %media a través de las columnas
                            y_final = mean(y_final, 2);
                        end
                        if size(y_current, 2) > 1
                            y_current = mean(y_current, 2);
                        end
                    end

                    % 6.Empatar longitudes (para poder sumarlas)
                    L_final = length(y_final); %num muestras señal base
                    L_current = length(y_current); %num muestras señal actual
                    
                    if L_final > L_current
                        % Rellenar la señal actual
                        %zeros(fil,col)
                        %size(y_current, 2): para saber cuantas columnas
                        %tiene y_current
                        %si la señal es mono: 1; y si es estéreo: 2
                        y_current = [y_current; zeros(L_final - L_current, size(y_current, 2))];
                    elseif L_current > L_final
                        % Rellenar la señal final
                        y_final = [y_final; zeros(L_current - L_final, size(y_final, 2))];
                    end
                    
                    % 7. Sumar
                    y_final = y_final + y_current;
                end
            
                
                % 8.Normalizar el resultado
                %al sumarlas puede que superen el máximo permitido (-1, 1)
                max_abs = max(abs(y_final(:))); %encuentra el valor de la muestra más alta
                %y_final(:) ----> una sola columna larga de números (por si es estéreo)

                %comprobamos si es mayor que el máximo permitido en valor absoluto (1)
                if max_abs > 1.0
                    y_final = y_final / max_abs;
                    app.TextArea.Value = 'Señales combinadas y normalizadas';
                else
                    app.TextArea.Value = 'Señales combinadas con éxito.';
                end
                
                % 9. Guardar la nueva señal
                %busca que señales empiezan por Combinada_ (startswith)
                numExistentes = sum(startsWith({app.SignalData.Name}, 'Combinada_'));
                newFilename = ['Combinada_', num2str(numExistentes + 1), '.wav'];

                newSignal.Name = newFilename;
                newSignal.Data = y_final;
                newSignal.Fs = Fs_target;
                app.SignalData(end+1) = newSignal;
                
                % 10. Actualizar GUI
                updateListBox(app);
                app.Archivos.Value = newFilename; % Seleccionar la nueva señal
                updatePlots(app);
                
            catch ME
                uialert(app.UIFigure, ['Error al combinar: ' ME.message], 'Error de Procesado');
                app.TextArea.Value = 'Error en la combinación.';
            end
        end

        % Button pushed function: AplicarRuidoButton
        function AplicarRuidoButtonPushed(app, event)
            % 1. Obtener señal seleccionada
            [y_original, Fs_original] = getSelectedSignal(app);


           % 2. VALIDACIÓN DE SELECCIÓN
            if isempty(y_original)
                % Si 'y_original' está vacío, es porque:
                % a) No se seleccionó nada.
                % b) Se seleccionaron >1 (getSelectedSignal ya mostró la alerta).
                
                % Si fue la opción (a), mostramos este mensaje:
                if isempty(app.Archivos.Value)
                   app.TextArea.Value = 'Error: Ningún archivo seleccionado. Use "+" para crear uno.';
                   uialert(app.UIFigure, 'Selecciona una señal para procesar.', 'Error');

                end
                return; % Salir de la función
            end
            selectedName = app.Archivos.Value{1};

            % 2. Obtener parámetro (Potencia) introducido por el usuario
            power = app.PotenciaRuidoEditField.Value; 

            % 3. Aplicar el procesamiento
            y_procesada = addGaussianNoise(app, y_original, power);
            
            if isempty(y_procesada)
                return; % no funciona
            end

            
                        %%%%%%%%%%%%%%%%%%%%%%%
            % Guardamos copia en la "Memoria Temporal" para el botón de Inicio 2
            app.ProcessedData = y_procesada;
            app.ProcessedFs = Fs_original;

                
            % 4. Guardar (Lógica de nombre secuencial para que cada uno se llame distinto)
            prefixBase = 'RuidoGaussiano';
            names = {app.SignalData.Name};
            numExistentes = sum(startsWith(names, prefixBase, 'IgnoreCase', true));
            newFilename = [prefixBase, num2str(numExistentes + 1), '.wav'];
            
            % 5. Almacenar
            newSignal.Name = newFilename;
            newSignal.Data = y_procesada;
            newSignal.Fs = Fs_original; % Fs no cambia
            app.SignalData(end+1) = newSignal;

            % 6. Actualizar GUI
            % 1. Dibuja la señal ORIGINAL
            updatePlots(app); 
            
            % 2. Dibuja la señal PROCESADA
            overlayProcessedPlot(app, y_procesada, Fs_original);

            % 3. Añade la nueva señal a la lista del gestor de archivos.
            updateListBox(app); 
            app.TextArea.Value = ['Ruido aplicado. Guardado como: ' newFilename];
        
        end

        % Button pushed function: AplicarButton
        function AplicarButtonPushed(app, event)

            % 1. Obtener la señal actualmente seleccionada (la que se va a sobrescribir)
            [y_original, Fs_original] = getSelectedSignal(app); 

           % 2. VALIDACIÓN DE SELECCIÓN
            if isempty(y_original)
                % Si 'y_original' está vacío, es porque:
                % a) No se seleccionó nada.
                % b) Se seleccionaron >1 (getSelectedSignal ya mostró la alerta).
                
                % Si fue la opción (a), mostramos este mensaje:
                if isempty(app.Archivos.Value)
                   app.TextArea.Value = 'Error: Ningún archivo seleccionado. Use "+" para crear uno.';
                  uialert(app.UIFigure, 'Selecciona una señal para procesar.', 'Error');
                end
                return; % Salir de la función
            end
            
            selectedName = app.Archivos.Value{1};
            % 1. Obtener parámetros dinámicos
            operation = app.SelectedOperation; % Viene del Toggle Group
            
            % 2. Valores por defecto (si la operación es Inversión Temporal)
            factor = 1;
            dominio = 'Ninguno';
            
            % 3. Lógica para Compresión/Expansión (Panel visible)
            if ~strcmp(operation, 'Inversion Temporal') % si comprimimos o expandimos
               
                % Leer Dominio y Factor
                dominio = app.DominiodeOperacionButtonGroup.SelectedObject.Text;
                factor = app.FactorEditField_2.Value; 

                % 1. Validación de Factor básico (debe ser positivo)
                if isempty(factor) || factor <= 1
                     uialert(app.UIFigure, 'El Factor debe ser un número superior a 1', 'Error de Parámetro');
                     return;
                end
               
            end
            
            
            % 4. Aplicar el procesamiento
            [y_procesada, Fs_procesada] = processSignal(app, y_original, Fs_original, operation, factor, dominio);
            
            if isempty(y_procesada)
                return; % Si función processSignal ha fallado (ej. fallo en Frecuencia)
            end
            
            % 5. Guardar la nueva señal
            % 5.1. Crear el prefijo del nombre
            % 'operation' = Compresion, Expansion, Inversion Temporal
            % strrep(operation, ' ', '_') reemplaza espacios por guiones bajos
            prefix = operation; % ej: 'Inversion_Temporal'

            % 5.2. Añadir dominio solo si no es Inversion Temporal
            if ~strcmp(operation, 'Inversion Temporal')
                 prefix = [prefix, ' ', dominio];
            end
            

                        %%%%%%%%%%%%%%%%%%%%%%%
            % Guardamos copia en la "Memoria Temporal" para el botón de Inicio 2
            app.ProcessedData = y_procesada;
            app.ProcessedFs = Fs_procesada;


            % 5.3. Contar señales existentes con ese mismo prefijo (ej: Compresion_Tiempo_x2)
            % Esto asegura que no sobrescribimos y que el contador siempre es correcto.
            names = {app.SignalData.Name};
            
            % Contamos cuántos archivos en la lista contienen nuestro nuevo prefijo 
            % IgnorreCase: asegura de que la búsqueda sea insensible a mayusculas o minúsculas
            numExistentes = sum(contains(names, prefix, 'IgnoreCase', true));
            
            % 5.4. Generar el nombre de archivo final
            % newFilename = 'Señal_Inversion_Temporal_1.wav'
            newFilename = ['Señal ', prefix, ' ', num2str(numExistentes + 1), '.wav'];

            % 5.5. Guardar la nueva señal
            newSignal.Name = newFilename;
            newSignal.Data = y_procesada;
            newSignal.Fs = Fs_procesada;
            
            app.SignalData(end+1) = newSignal;
            
            % 6. Actualizar la GUI
           % 1. Dibuja la señal ORIGINAL (que sigue seleccionada) en azul.
            updatePlots(app); 
            
            % 2. Dibuja la señal PROCESADA 
            overlayProcessedPlot(app, y_procesada, Fs_procesada);

            % 3. Añade la nueva señal a la lista del gestor de archivos.
            updateListBox(app); 

            app.TextArea.Value = ['Procesado aplicado. Nueva señal guardada como: ' newFilename];
        
        end

        % Button pushed function: AplicarFiltroButton
        function AplicarFiltroButtonPushed(app, event)
            % 1. Obtener señal seleccionada
            [y_original, Fs_original] = getSelectedSignal(app);


           % 2. VALIDACIÓN DE SELECCIÓN
            if isempty(y_original)
                % Si 'y_original' está vacío, es porque:
                % a) No se seleccionó nada.
                % b) Se seleccionaron >1 (getSelectedSignal ya mostró la alerta).
                
                % Si fue la opción (a), mostramos este mensaje:
                if isempty(app.Archivos.Value)
                   app.TextArea.Value = 'Error: Ningún archivo seleccionado. Use "+" para crear uno.';
                end
                return; % Salir de la función
            end
            selectedName = app.Archivos.Value{1};

            % 2. Obtener parámetro (Frec Corte) introducida por el usuario
            Fc = app.FrecCorteEditField.Value; 

            % 3. Aplicar el procesamiento (llama al helper)
            y_procesada = applyLowPassFilter(app, y_original, Fs_original, Fc);
            
            if isempty(y_procesada)
                return; % Falló la validación en el helper
            end

                        %%%%%%%%%%%%%%%%%%%%%%%
            % Guardamos copia en la "Memoria Temporal" para el botón de Inicio 2
            app.ProcessedData = y_procesada;
            app.ProcessedFs = Fs_original;


            % 4. Guardar (Lógica de nombre secuencial)
            prefixBase = 'FiltroPB'; % Filtro Paso Bajo
            names = {app.SignalData.Name};
            numExistentes = sum(startsWith(names, prefixBase, 'IgnoreCase', true));
            newFilename = [prefixBase, num2str(numExistentes + 1), '.wav'];

            % 5. Almacenar
            newSignal.Name = newFilename;
            newSignal.Data = y_procesada;
            newSignal.Fs = Fs_original; % Fs no cambia
            app.SignalData(end+1) = newSignal;

            % 6. Actualizar GUI
            % 1. Dibuja la señal ORIGINAL (que sigue seleccionada) en azul.
            updatePlots(app); 
            
            % 2. Dibuja la señal PROCESADA en rojo encima.
            overlayProcessedPlot(app, y_procesada, Fs_original);

            % 3. Añade la nueva señal a la lista del gestor de archivos.
            updateListBox(app); 
            
              
            app.TextArea.Value = ['Filtro aplicado. Guardado como: ' newFilename];
        end

        % Value changed function: FormaOnda
        function FormaOndaValueChanged(app, event)
            value = app.FormaOnda.Value;
            % PESTAÑA GENERADOR
            % No aparecen todos los paneles simultaneamente
            % 1. Reset: Ocultar TODOS los paneles opcionales primero
            %app.OndaCuadradaPanel.Visible = 'off';
            %app.OndaTriangularPanel.Visible = 'off';
            %app.OndaTrapezoidePanel.Visible = 'off'; 
            
            % 2. Mostrar solo el necesario
            switch value   
                case 'Diente de Sierra' 
                    app.SimetriaEditField.Value = 1; % Forzar simetría 1 (Sierra)
                   
            end
        end

        % Button pushed function: ModularButton
        function ModularButtonPushed(app, event)
            % 1. Obtener señal
            [y_original, Fs_original] = getSelectedSignal(app);
            if isempty(y_original)
                app.TextArea.Value = 'Error: Ningún archivo seleccionado.';
                uialert(app.UIFigure, 'Selecciona una señal.', 'Error');
                return;
            end
            
            % 2. Obtener Parámetros
            Fc = app.FrecuenciaPortadoraEditField.Value;
            tipo = app.TipoModulacionButtonGroup.SelectedObject.Text; % 'AM' o 'FM'
            
            % 3. Validaciones
            if Fc <= 0 || Fc >= (Fs_original/2)
                uialert(app.UIFigure, 'La portadora debe ser > 0 y < Fs/2 (Nyquist).', 'Error Fc');
                return;
            end
            
            % 4. Elegir Modulación
            if strcmp(tipo, 'AM')
                y_proc = performAMMod(app, y_original, Fs_original, Fc);
            elseif strcmp(tipo, 'FM')
                y_proc = performFMMod(app, y_original, Fs_original, Fc);
            else
                return;
            end
            
            if isempty(y_proc), return; end
            
            %%%%%%%%%%%%%%%%%%%%%%%
            % Guardamos copia en la "Memoria Temporal" para el botón de Inicio 2
            app.ProcessedData = y_proc;
            app.ProcessedFs = Fs_original;


            % 5. Guardar
            prefix = ['Mod_', tipo];
            names = {app.SignalData.Name};
            numExistentes = sum(startsWith(names, prefix, 'IgnoreCase', true));
            newFilename = [prefix, num2str(numExistentes + 1), '.wav'];
            
            newSignal.Name = newFilename;
            newSignal.Data = y_proc;
            newSignal.Fs = Fs_original;
            app.SignalData(end+1) = newSignal;
            
            % 6. Actualizar
            updateListBox(app);
            updatePlots(app);
            overlayProcessedPlot(app, y_proc, Fs_original);
        end

        % Button pushed function: DemodularButton
        function DemodularButtonPushed(app, event)
            % 1. Obtener señal (se asume que es una señal ya modulada)
            [y_original, Fs_original] = getSelectedSignal(app);
            if isempty(y_original)
                app.TextArea.Value = 'Error: Ningún archivo seleccionado.';
                uialert(app.UIFigure, 'Selecciona una señal modulada.', 'Error');
                return;
            end
            
            % 2. Obtener Parámetros
            Fc = app.FrecuenciaPortadoraEditField.Value;
            tipo = app.TipoModulacionButtonGroup.SelectedObject.Text; 
            
            if Fc <= 0, uialert(app.UIFigure, 'Introduce la Frecuencia Portadora correcta.', 'Error'); return; end
            
            % 3. Elegir Demodulación
            if strcmp(tipo, 'AM')
                y_proc = performAMDemod(app, y_original, Fs_original, Fc);
            elseif strcmp(tipo, 'FM')
                y_proc = performFMDemod(app, y_original, Fs_original, Fc);
            else
                return;
            end
            
            if isempty(y_proc), return; end
            
            %%%%%%%%%%%%%%%%%%%%%%%
            % Guardamos copia en la "Memoria Temporal" para el botón de Inicio 2
            app.ProcessedData = y_proc;
            app.ProcessedFs = Fs_original;

            % 4. Guardar
            prefix = ['Demod_', tipo];
            names = {app.SignalData.Name};
            numExistentes = sum(startsWith(names, prefix, 'IgnoreCase', true));
            newFilename = [prefix, num2str(numExistentes + 1), '.wav'];
            
            newSignal.Name = newFilename;
            newSignal.Data = y_proc;
            newSignal.Fs = Fs_original;
            app.SignalData(end+1) = newSignal;
            
            % 5. Actualizar
            updateListBox(app);
            updatePlots(app);
            overlayProcessedPlot(app, y_proc, Fs_original);
        end

        % Button pushed function: AliasingButton
        function AliasingButtonPushed(app, event)
            [y_original, Fs_original] = getSelectedSignal(app);
            if isempty(y_original)
                app.TextArea.Value = 'Error: Ningún archivo seleccionado.';
                uialert(app.UIFigure, 'Selecciona una señal.', 'Error');
                return;
            end
            
            Fs_modificada = str2double(app.Aliasing.Value);

            % Validaciones de seguridad
             if isnan(Fs_modificada) || Fs_modificada <= 0
                uialert(app.UIFigure, 'Introduce una Frecuencia válida (Hz > 0).', 'Error de Parámetro');
                return;
            end

            paso = round(Fs_original / Fs_modificada);
    
            if paso < 1
              paso = 1; % Por seguridad, para no romper nada
            end
            
            %remuestreamos la señal con menos muestras
            y_aliased = downsample(y_original, paso);
            
            % Extraemos la frecuencia máxima de la señal original
            L = length(y_original);
            Y = fft(y_original); 
            P2 = abs(Y/L);
            P1 = P2(1:floor(L/2)+1);
            f_axis = Fs_original * (0:floor(L/2)) / L;

            % Buscamos el pico más alto (ignorando ruido muy bajo)
            umbral = 0.01 * max(P1); 
            %empieza desde los agudos hacia los graves y que para cuando
            %encuentre el primero
            idx_max = find(P1 > umbral, 1, 'last'); 
    
            if isempty(idx_max)
                f_max_real = 0;
            else
                f_max_real = f_axis(idx_max);
            end
            
            % Guardamos copia en la "Memoria Temporal" para el botón de Inicio 2
            app.ProcessedData = y_aliased;
            app.ProcessedFs = Fs_modificada;

            % Frecuencia de Nyquist
            fn= 2*f_max_real;

            if(Fs_modificada>fn) %Teorema de Nyquist
                 respuesta = sprintf('NO');
            else 
                respuesta = sprintf('SI');
            end
            mensaje = sprintf('Frec máx señal:\n %.0f \n¿Aliasing?: %s', f_max_real, respuesta);
            app.NyquistArea.Value= mensaje;

             % Guardar
            prefix = ['Aliasing_'];
            names = {app.SignalData.Name};
            numExistentes = sum(startsWith(names, prefix, 'IgnoreCase', true));
            newFilename = [prefix, num2str(numExistentes + 1), '.wav'];

            newSignal.Name = newFilename;
            newSignal.Data = y_aliased;
            newSignal.Fs = Fs_modificada;
            app.SignalData(end+1) = newSignal;

            
            updatePlots(app);
          
            % Dibuja la señal PROCESADA
            overlayProcessedPlot(app, y_aliased, Fs_modificada);

            % Actualizar
            updateListBox(app);
            %app.Archivos.Value = newFilename;
            app.TextArea.Value = ['Aliasing aplicado. Guardado como: ' newFilename];

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.GeneradorTab = uitab(app.TabGroup);
            app.GeneradorTab.Title = 'Generador';
            app.GridLayout12 = uigridlayout(app.GeneradorTab);
            app.FrecuenciaLabel = uilabel(app.GridLayout12);
            app.FrecuenciaLabel.Text = 'Frec. señal (Hz)';
            app.FrecuenciaGenerada = uieditfield(app.GridLayout12, 'numeric');
            app.AmplitudLabel = uilabel(app.GridLayout12);
            app.AmplitudLabel.Text = 'Amplitud (V)';
            app.FormadeOndaLabel = uilabel(app.GridLayout12);
            app.FormadeOndaLabel.Text = 'Forma de Onda';
            app.FormaOnda = uidropdown(app.GridLayout12);
            app.FormaOnda.Items = {'Senoidal', 'Cuadrada', 'Triangular', 'Diente de Sierra', 'Trapezoidal'};
            app.FormaOnda.ValueChangedFcn = createCallbackFcn(app, @FormaOndaValueChanged, true);
            app.FormaOnda.Value = 'Senoidal';
            app.AmplitudGenerada = uieditfield(app.GridLayout12, 'numeric');
            app.AmplitudLabel_2 = uilabel(app.GridLayout12);
            app.AmplitudLabel_2.Text = 'Desfase (grados º)';
            app.DesfasegradosEditField = uieditfield(app.GridLayout12, 'numeric');
            app.GrabacinPanel = uipanel(app.GridLayout12);
            app.GrabacinPanel.Title = 'Grabación';
            app.RecordButton = uibutton(app.GrabacinPanel, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Grabar/Detener';
            app.RecordingStatusLabel = uitextarea(app.GrabacinPanel);
            app.GenerarSealButton = uibutton(app.GridLayout12, 'push');
            app.GenerarSealButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarSealButtonPushed, true);
            app.GenerarSealButton.Text = 'Generar Señal';
            app.OndaCuadradaPanel = uibuttongroup(app.GridLayout12);
            app.OndaCuadradaPanel.Title = 'Onda Cuadrada';
            app.DutyCycleLabel = uilabel(app.OndaCuadradaPanel);
            app.DutyCycleLabel.Text = 'Duty Cycle %';
            app.DutyCycleEditField = uieditfield(app.OndaCuadradaPanel, 'numeric');
            app.OndaTriangularPanel = uibuttongroup(app.GridLayout12);
            app.OndaTriangularPanel.Title = 'Onda Triangular';
            app.SimetraLabel = uilabel(app.OndaTriangularPanel);
            app.SimetraLabel.Text = 'Simetría [0-1]';
            app.SimetriaEditField = uieditfield(app.OndaTriangularPanel, 'numeric');
            app.TrapezoidalPanel = uipanel(app.GridLayout12);
            app.TrapezoidalPanel.Title = 'Trapezoidal';
            app.GridLayout22 = uigridlayout(app.TrapezoidalPanel);
            app.TiempodesubidaLabel = uilabel(app.GridLayout22);
            app.TiempodesubidaLabel.Text = 'Tiempo de subida %';
            app.TiempoSubidaEditField = uieditfield(app.GridLayout22, 'numeric');
            app.TiempodesubidaLabel_2 = uilabel(app.GridLayout22);
            app.TiempodesubidaLabel_2.Text = 'Tiempo de bajada %';
            app.TiempoBajadaEditField = uieditfield(app.GridLayout22, 'numeric');
            app.TiempodesubidaLabel_3 = uilabel(app.GridLayout22);
            app.TiempodesubidaLabel_3.Text = 'Tiempo en alto %';
            app.TiempoAltoEditField = uieditfield(app.GridLayout22, 'numeric');
            app.FrecuenciaLabel_2 = uilabel(app.GridLayout12);
            app.FrecuenciaLabel_2.Text = 'Frec. muestreo (Hz)';
            app.FrecMuestreoEditField = uieditfield(app.GridLayout12, 'numeric');
            app.FrecMuestreoEditField.Value = 44100;
            app.FrecuenciaLabel_3 = uilabel(app.GridLayout12);
            app.FrecuenciaLabel_3.Text = 'Duración';
            app.DuracionEditField = uieditfield(app.GridLayout12, 'numeric');
            app.DuracionEditField.Value = 1;
            app.ProcesadoTab = uitab(app.TabGroup);
            app.ProcesadoTab.Title = 'Procesado';
            app.GridLayout13 = uigridlayout(app.ProcesadoTab);
            app.OpcionesPanel = uipanel(app.GridLayout13);
            app.OpcionesPanel.Title = 'Opciones';
            app.GridLayout14 = uigridlayout(app.OpcionesPanel);
            app.DominiodeOperacionButtonGroup = uibuttongroup(app.GridLayout14);
            app.DominiodeOperacionButtonGroup.Title = 'Dominio de Operación';
            app.TiempoButton = uiradiobutton(app.DominiodeOperacionButtonGroup);
            app.TiempoButton.Text = 'Tiempo';
            app.TiempoButton.Value = true;
            app.FrecuenciaButton = uiradiobutton(app.DominiodeOperacionButtonGroup);
            app.FrecuenciaButton.Text = 'Frecuencia';
            app.FactorEditField_2Label = uilabel(app.GridLayout14);
            app.FactorEditField_2Label.Text = 'Factor';
            app.FactorEditField_2 = uieditfield(app.GridLayout14, 'numeric');
            app.AplicarButton = uibutton(app.GridLayout14, 'push');
            app.AplicarButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarButtonPushed, true);
            app.AplicarButton.Text = 'Aplicar';
            app.OperacionesElementalesButtonGroup = uibuttongroup(app.GridLayout13);
            app.OperacionesElementalesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OperacionesElementalesButtonGroupSelectionChanged, true);
            app.OperacionesElementalesButtonGroup.Title = 'Operaciones Elementales';
            app.CompresionButton = uitogglebutton(app.OperacionesElementalesButtonGroup);
            app.CompresionButton.Text = 'Compresion';
            app.CompresionButton.Value = true;
            app.ExpansionButton = uitogglebutton(app.OperacionesElementalesButtonGroup);
            app.ExpansionButton.Text = 'Expansion';
            app.InversionTemporalButton = uitogglebutton(app.OperacionesElementalesButtonGroup);
            app.InversionTemporalButton.Text = 'Inversion Temporal';
            app.RUIDOPanel = uipanel(app.GridLayout13);
            app.RUIDOPanel.Title = 'RUIDO';
            app.GridLayout16 = uigridlayout(app.RUIDOPanel);
            app.PotenciaEditFieldLabel = uilabel(app.GridLayout16);
            app.PotenciaEditFieldLabel.Text = 'Potencia';
            app.PotenciaRuidoEditField = uieditfield(app.GridLayout16, 'numeric');
            app.RuidoGaussianoLabel = uilabel(app.GridLayout16);
            app.RuidoGaussianoLabel.Text = 'Ruido Gaussiano';
            app.AplicarRuidoButton = uibutton(app.GridLayout16, 'push');
            app.AplicarRuidoButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarRuidoButtonPushed, true);
            app.AplicarRuidoButton.Text = 'Aplicar';
            app.FiltrecuperacinFPBLabel = uilabel(app.GridLayout16);
            app.FiltrecuperacinFPBLabel.Text = 'Filt. recuperación (FPB)';
            app.freccorteHzLabel = uilabel(app.GridLayout16);
            app.freccorteHzLabel.Text = 'Fc (Hz)';
            app.FrecCorteEditField = uieditfield(app.GridLayout16, 'numeric');
            app.AplicarFiltroButton = uibutton(app.GridLayout16, 'push');
            app.AplicarFiltroButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarFiltroButtonPushed, true);
            app.AplicarFiltroButton.Text = 'Aplicar';
            app.ModDemodPanel = uipanel(app.GridLayout13);
            app.ModDemodPanel.Title = 'Modulación/Demodulación';
            app.GridLayout17 = uigridlayout(app.ModDemodPanel);
            app.TipoModulacionButtonGroup = uibuttongroup(app.GridLayout17);
            app.AMButton = uiradiobutton(app.TipoModulacionButtonGroup);
            app.AMButton.Text = 'AM';
            app.AMButton.Value = true;
            app.FMButton = uiradiobutton(app.TipoModulacionButtonGroup);
            app.FMButton.Text = 'FM';
            app.FrecuenciaPortadoraFieldLabel = uilabel(app.GridLayout17);
            app.FrecuenciaPortadoraFieldLabel.Text = 'Frecuencia portadora ';
            app.FrecuenciaPortadoraEditField = uieditfield(app.GridLayout17, 'numeric');
            app.DemodularButton = uibutton(app.GridLayout17, 'push');
            app.DemodularButton.ButtonPushedFcn = createCallbackFcn(app, @DemodularButtonPushed, true);
            app.DemodularButton.Text = 'Demodular';
            app.ModularButton = uibutton(app.GridLayout17, 'push');
            app.ModularButton.ButtonPushedFcn = createCallbackFcn(app, @ModularButtonPushed, true);
            app.ModularButton.Text = 'Modular';
            app.HzLabel = uilabel(app.GridLayout17);
            app.HzLabel.Text = 'Hz';
            app.AliasingPanel = uipanel(app.GridLayout13);
            app.AliasingPanel.Title = 'Aliasing';
            app.GridLayout19 = uigridlayout(app.AliasingPanel);
            app.IntroduzcaunanuevaFsLabel = uilabel(app.GridLayout19);
            app.IntroduzcaunanuevaFsLabel.Text = 'Introduzca una nueva Fs (Hz):';
            app.Aliasing = uieditfield(app.GridLayout19, 'text');
            app.AliasingButton = uibutton(app.GridLayout19, 'push');
            app.AliasingButton.ButtonPushedFcn = createCallbackFcn(app, @AliasingButtonPushed, true);
            app.AliasingButton.Text = 'Aplicar';
            app.NyquistArea = uitextarea(app.GridLayout19);
            app.GestordearchivosPanel = uipanel(app.GridLayout);
            app.GestordearchivosPanel.Title = 'Gestor de archivos';
            app.GridLayout4 = uigridlayout(app.GestordearchivosPanel);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.Button_3 = uibutton(app.GridLayout5, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @nuevoButtonPushed, true);
            app.Button_3.Icon = fullfile(pathToMLAPP, 'iconos', 'nuevo.png');
            app.Button_3.Text = '';
            app.Button_5 = uibutton(app.GridLayout5, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Icon = fullfile(pathToMLAPP, 'iconos', 'limpiar2.png');
            app.Button_5.Text = '';
            app.Button = uibutton(app.GridLayout5, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'iconos', 'papelera.png');
            app.Button.Text = '';
            app.Button_2 = uibutton(app.GridLayout5, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = fullfile(pathToMLAPP, 'iconos', 'load.png');
            app.Button_2.Text = '';
            app.GuardarButton = uibutton(app.GridLayout5, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Icon = fullfile(pathToMLAPP, 'iconos', 'save.png');
            app.GuardarButton.Text = '';
            app.GridLayout6 = uigridlayout(app.GridLayout4);
            app.ListBoxLabel = uilabel(app.GridLayout6);
            app.ListBoxLabel.Text = 'List Box';
            app.Archivos = uilistbox(app.GridLayout6);
            app.Archivos.Items = {''};
            app.Archivos.Multiselect = 'on';
            app.Archivos.Value = {''};
            app.GridLayout7 = uigridlayout(app.GridLayout4);
            app.TextArea = uitextarea(app.GridLayout4);
            app.TextArea.Editable = 'off';
            app.CombinarButton = uibutton(app.GridLayout4, 'push');
            app.CombinarButton.ButtonPushedFcn = createCallbackFcn(app, @CombinarButtonPushed, true);
            app.CombinarButton.Text = 'Combinar señales';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.UIAxesFrecuencia = uiaxes(app.GridLayout2);
            title(app.UIAxesFrecuencia, 'Espectro de Amplitud (FFT)')
            xlabel(app.UIAxesFrecuencia, 'Frecuencia (Hz)')
            ylabel(app.UIAxesFrecuencia, 'Amplitud |Y(f)|')
            zlabel(app.UIAxesFrecuencia, 'Z')
            app.UIAxesSpectrogram = uiaxes(app.GridLayout2);
            title(app.UIAxesSpectrogram, 'Espectrograma')
            xlabel(app.UIAxesSpectrogram, 'Tiempo (s)')
            ylabel(app.UIAxesSpectrogram, 'Frecuencia (Hz)')
            zlabel(app.UIAxesSpectrogram, 'Z')
            app.UIAxesTiempo = uiaxes(app.GridLayout2);
            title(app.UIAxesTiempo, 'Señal:')
            xlabel(app.UIAxesTiempo, 'Tiempo (s)')
            ylabel(app.UIAxesTiempo, 'Amplitud (v)')
            zlabel(app.UIAxesTiempo, 'Z')
            app.GridLayout10 = uigridlayout(app.GridLayout2);
            app.GridLayout20 = uigridlayout(app.GridLayout10);
            app.ActualizarButton = uibutton(app.GridLayout20, 'push');
            app.ActualizarButton.ButtonPushedFcn = createCallbackFcn(app, @ActualizarButtonPushed, true);
            app.ActualizarButton.Icon = fullfile(pathToMLAPP, 'iconos', 'actualizar.png');
            app.ActualizarButton.Text = 'Actualizar';
            app.PlayPauseButton = uibutton(app.GridLayout20, 'push');
            app.PlayPauseButton.ButtonPushedFcn = createCallbackFcn(app, @PlayPauseButtonPushed, true);
            app.PlayPauseButton.Icon = fullfile(pathToMLAPP, 'iconos', 'play_pause.png');
            app.PlayPauseButton.Text = 'Play/Pause';
            app.InicioButton = uibutton(app.GridLayout20, 'push');
            app.InicioButton.ButtonPushedFcn = createCallbackFcn(app, @InicioButtonPushed, true);
            app.InicioButton.Icon = fullfile(pathToMLAPP, 'iconos', 'begin.png');
            app.InicioButton.Text = 'Inicio';
            app.PotenciamediaButton = uibutton(app.GridLayout10, 'push');
            app.PotenciamediaButton.ButtonPushedFcn = createCallbackFcn(app, @PotenciamediaButtonPushed, true);
            app.PotenciamediaButton.Text = 'Potencia media';
            app.TextPotenciamedia = uitextarea(app.GridLayout10);
            app.NiveldepresinsonoraButton = uibutton(app.GridLayout10, 'push');
            app.NiveldepresinsonoraButton.ButtonPushedFcn = createCallbackFcn(app, @NiveldepresinsonoraButtonPushed, true);
            app.NiveldepresinsonoraButton.Text = 'Nivel de presión sonora';
            app.TextPresion = uitextarea(app.GridLayout10);
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.UIAxesTiempoProc = uiaxes(app.GridLayout3);
            title(app.UIAxesTiempoProc, 'Señal procesada:')
            xlabel(app.UIAxesTiempoProc, 'Tiempo (s)')
            ylabel(app.UIAxesTiempoProc, 'Amplitud(V)')
            zlabel(app.UIAxesTiempoProc, 'Z')
            app.UIAxesFrecuenciaProc = uiaxes(app.GridLayout3);
            title(app.UIAxesFrecuenciaProc, 'Espectro')
            xlabel(app.UIAxesFrecuenciaProc, 'X')
            ylabel(app.UIAxesFrecuenciaProc, 'Y')
            zlabel(app.UIAxesFrecuenciaProc, 'Z')
            app.UIAxesSpectrogramProc = uiaxes(app.GridLayout3);
            title(app.UIAxesSpectrogramProc, 'Espectrograma S. procesada:')
            xlabel(app.UIAxesSpectrogramProc, 'Tiempo (s)')
            ylabel(app.UIAxesSpectrogramProc, 'Frecuencia (Hz)')
            zlabel(app.UIAxesSpectrogramProc, 'Z')
            app.GridLayout20_2 = uigridlayout(app.GridLayout3);
            app.InicioButton_2 = uibutton(app.GridLayout20_2, 'push');
            app.InicioButton_2.ButtonPushedFcn = createCallbackFcn(app, @InicioButton_2Pushed, true);
            app.InicioButton_2.Icon = fullfile(pathToMLAPP, 'iconos', 'begin.png');
            app.InicioButton_2.Text = 'Inicio';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end